classdef autoTrimApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        AverageSwitch                 matlab.ui.control.Switch
        AverageSwitchLabel            matlab.ui.control.Label
        INDIVIDUALRESULTSLabel        matlab.ui.control.Label
        FINALAVERAGERESULTSLabel      matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        SelectnumberofdivesbacktoprocessLabel  matlab.ui.control.Label
        SelectlastdivetoprocessLabel  matlab.ui.control.Label
        SelectfoldercontainingncfilestoprocessmustbenamedsgGGGLabel  matlab.ui.control.Label
        LastDiveDropDown              matlab.ui.control.DropDown
        LastDiveDropDownLabel         matlab.ui.control.Label
        SelectfolderButton            matlab.ui.control.Button
        ResultsTextArea               matlab.ui.control.TextArea
        CreatecmdfileCheckBox         matlab.ui.control.CheckBox
        NoofdivesSpinner              matlab.ui.control.Spinner
        NoofdivesSpinnerLabel         matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        sgid % Description
        lastDive % Description
        diveSelected
        totalDives % Description
        select_folder
        last_file
        createcmd
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
          
            % get numeric last dive
            app.diveSelected = str2num(app.diveSelected(5:8));
            
            % get values from GUI
            app.totalDives = app.NoofdivesSpinner.Value;
            app.createcmd = app.CreatecmdfileCheckBox.Value;

%             app.UITable.Data = [];
%             app.UITable.Data = [];
%             myresults = [];
%             myfinalresults = [];
            
            % Start the script
            [ myresults, myfinalresults] = autoTrimv4_gui(str2double(app.sgid),app.diveSelected,app.totalDives,app.select_folder, app.createcmd, app.AverageSwitch.Value );
%             Pass the following values 
%             ncDirName = 'C:\Users\christian.saiz\Documents\0_NOAA\1_NOAA_work\Gliders\aoml.glider scripts\Matlab\AutoPilot_slim\ncfile\sg670';
%             sgid = 670;
%             lastDive = 290;
%             totalDives = 3;
%             autoTrimv4_gui(sgid,lastDive,totalDives,ncDirName);

            % Show values in table
%             mytable = uitable(myresults);
            
            app.UITable.Data = myresults;
            app.UITable.ColumnFormat(20) = {'bank'};
            app.UITable2.Data = myfinalresults;


        end

        % Callback function
        function SeaGliderEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function LastDiveEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: NoofdivesSpinner
        function NoofdivesSpinnerValueChanged(app, event)
            app.totalDives = app.NoofdivesSpinner.Value;
            
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            % Folder selection
            app.select_folder = uigetdir(); % brings full absolute path
            sgfolder = split(app.select_folder,'\'); % split by /
            sgfolder = sgfolder(end); % take only last directory 'sgGGG'
            app.ResultsTextArea.Value = sgfolder; % sgGGG
            app.sgid = erase(sgfolder,'sg'); % get seaglider ID 'GGG'

            % Prepare Dropdown menu
            filter_files = strcat(app.select_folder,'\*.nc');

            file_list = dir(filter_files); % find files in sgfolder selected
            file_list = {file_list.name}; % list with pGGGDDDD.nc files
            file_list = sort(file_list); % sorted list 
            
            app.LastDiveDropDown.Items = file_list; % use file list in dropdown

            % Get last dive of the list
            app.last_file = file_list(end); % take last dive file pGGGDDDD.nc                     


%             app.ResultsTextArea.Value = app.select_folder;

        end

        % Value changed function: ResultsTextArea
        function ResultsTextAreaValueChanged(app, event)
            value = app.ResultsTextArea.Value;
            
        end

        % Callback function
        function DisplayButtonPushed(app, event)
            app.ResultsTextArea.Value = app.select_folder;
        end

        % Value changed function: LastDiveDropDown
        function LastDiveDropDownValueChanged(app, event)
            app.diveSelected = app.LastDiveDropDown.Value;

            
        end

        % Value changed function: CreatecmdfileCheckBox
        function CreatecmdfileCheckBoxValueChanged(app, event)
            value = app.CreatecmdfileCheckBox.Value;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;


        end

        % Value changed function: AverageSwitch
        function AverageSwitchValueChanged(app, event)
            value = app.AverageSwitch.Value;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NoofdivesSpinnerLabel = uilabel(app.UIFigure);
            app.NoofdivesSpinnerLabel.Text = 'No. of dives';
            app.NoofdivesSpinner = uispinner(app.UIFigure);
            app.NoofdivesSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofdivesSpinnerValueChanged, true);
            app.NoofdivesSpinner.Value = 3;
            app.CreatecmdfileCheckBox = uicheckbox(app.UIFigure);
            app.CreatecmdfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @CreatecmdfileCheckBoxValueChanged, true);
            app.CreatecmdfileCheckBox.Text = 'Create cmdfile';
            app.CreatecmdfileCheckBox.Value = true;
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.ValueChangedFcn = createCallbackFcn(app, @ResultsTextAreaValueChanged, true);
            app.ResultsTextArea.Editable = 'off';
            app.ResultsTextArea.Value = {'sgGGG'};
            app.SelectfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Icon = 'browse-icon-3.jpg';
            app.SelectfolderButton.Text = 'Select folder';
            app.LastDiveDropDownLabel = uilabel(app.UIFigure);
            app.LastDiveDropDownLabel.Text = 'Last Dive';
            app.LastDiveDropDown = uidropdown(app.UIFigure);
            app.LastDiveDropDown.Items = {'-'};
            app.LastDiveDropDown.ValueChangedFcn = createCallbackFcn(app, @LastDiveDropDownValueChanged, true);
            app.LastDiveDropDown.Value = '-';
            app.SelectfoldercontainingncfilestoprocessmustbenamedsgGGGLabel = uilabel(app.UIFigure);
            app.SelectfoldercontainingncfilestoprocessmustbenamedsgGGGLabel.Text = 'Select folder containing .nc files to process: (must be named ''sgGGG'')';
            app.SelectlastdivetoprocessLabel = uilabel(app.UIFigure);
            app.SelectlastdivetoprocessLabel.Text = 'Select last dive to process:';
            app.SelectnumberofdivesbacktoprocessLabel = uilabel(app.UIFigure);
            app.SelectnumberofdivesbacktoprocessLabel.Text = 'Select number of dives back to process:';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'DiveN'; 'CVBD'; 'iCVBD'; 'CPITCH'; 'iCPITCH'; 'PGAIN'; 'iPGAIN'; 'CROLLDIVE'; 'iCROLLDIVE'; 'iCROLLTURNDIVE'; 'CROLLDIVEAVE'; 'CROLLCLIMB'; 'iCROLLCLIMB'; 'iCROLLTURNCLIMB'; 'CROLLCLIMBAVE'; 'SMCC'; 'SURFMIN'; 'iSMCC'; 'MAXBUOY'; 'DISTKM'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false false false false false false false false false false false false false false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'DiveN'; 'CVBD'; 'iCVBD'; 'CPITCH'; 'iCPITCH'; 'PGAIN'; 'iPGAIN'; 'CROLLDIVE'; 'iCROLLDIVE'; 'iCROLLTURNDIVE'; 'CROLLDIVEAVE'; 'CROLLCLIMB'; 'iCROLLCLIMB'; 'iCROLLTURNCLIMB'; 'CROLLCLIMBAVE'; 'SMCC'; 'SURFMIN'; 'iSMCC'; 'MAXBUOY'; 'DISTKM'};
            app.UITable2.RowName = {};
            app.FINALAVERAGERESULTSLabel = uilabel(app.UIFigure);
            app.FINALAVERAGERESULTSLabel.Text = 'FINAL AVERAGE RESULTS';
            app.INDIVIDUALRESULTSLabel = uilabel(app.UIFigure);
            app.INDIVIDUALRESULTSLabel.Text = 'INDIVIDUAL RESULTS';
            app.AverageSwitchLabel = uilabel(app.UIFigure);
            app.AverageSwitchLabel.Text = 'Average';
            app.AverageSwitch = uiswitch(app.UIFigure, 'slider');
            app.AverageSwitch.Items = {'Simple', 'Weighed'};
            app.AverageSwitch.ValueChangedFcn = createCallbackFcn(app, @AverageSwitchValueChanged, true);
            app.AverageSwitch.Value = 'Simple';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Select final results average type (weighed dive number or simple average):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autoTrimApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end